classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CleaningButton              matlab.ui.control.Button
        PredictOutcomesTextArea     matlab.ui.control.TextArea
        Label_16                    matlab.ui.control.Label
        ResultStorageButton         matlab.ui.control.Button
        BasicInformationPanel       matlab.ui.container.Panel
        ACEIARBDropDown             matlab.ui.control.DropDown
        ACEIARBDropDownLabel        matlab.ui.control.Label
        LDHEditField                matlab.ui.control.NumericEditField
        LDHEditFieldLabel           matlab.ui.control.Label
        CRPEditField                matlab.ui.control.NumericEditField
        CRPEditFieldLabel           matlab.ui.control.Label
        IL6EditField                matlab.ui.control.NumericEditField
        IL6EditFieldLabel           matlab.ui.control.Label
        MulbstascoreEditField       matlab.ui.control.NumericEditField
        MulbstascoreEditFieldLabel  matlab.ui.control.Label
        AgeEditField                matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        StartPredictionButton       matlab.ui.control.Button
        Label_17                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        x % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartPredictionButton
        function StartPredictionButtonPushed(app, event)
            app.x=1:11; % x赋予初始值  
            
            % 第1个自变量:年龄x(1)
            app.x(1)=app.AgeEditField.Value;   
            
            % 第2个自变量:民族x(2)
            A=app.ACEIARBDropDown.Value;
            switch A
                case '汉族'
                    app.x(2)=1;
                case '少数民族'
                    app.x(2)=2;
            end
     
            % 第3个自变量:地域x(3)
            A=app.DropDown_10.Value;
            switch A
                case '东北'
                    app.x(3)=1;
                case '华东'
                    app.x(3)=2;
                case '华北'
                    app.x(3)=3;
                case '华中'
                    app.x(3)=4;
                case '华南'
                    app.x(3)=5;
                case '西南'
                    app.x(3)=6;
                case '西北'
                    app.x(3)=7;
            end
            
            % 第4个自变量:高原防治知识储备x(4)
            A=app.PulmonaryFunctionClassificationDropDown.Value;
            switch A
                case '非常了解'
                    app.x(4)=1;
                case '一般了解'
                    app.x(4)=2;
                case '完全不了解'
                    app.x(4)=3;
            end

            % 第5个自变量:足够御寒衣物x(5)
            A=app.DropDown_3.Value;
            switch A
                case '是'
                    app.x(5)=1;
                case '否'
                    app.x(5)=0;
            end

            % 第6个自变量：上高原前睡眠质量x(6)
            A=app.DropDown_4.Value;
            switch A
                case '睡眠质量好'
                    app.x(6)=1;
                case '睡眠质量低'
                    app.x(6)=2;
                case '无法入睡'
                    app.x(6)=3;
                case '服药后入睡'
                    app.x(6)=4;
            end

            % 第7个自变量：头痛x(7)
            A=app.DropDown_7.Value;
            switch A
                case '无'
                    app.x(7)=0;
                case '有'
                    app.x(7)=1;
            end

            % 第8个自变量：疲劳/乏力x(8)
            A=app.DropDown_8.Value;
            switch A
                case '无'
                    app.x(8)=0;
                case '有'
                    app.x(8)=1;
            end

            % 第9个自变量：头晕/头昏x(9)
            A=app.DropDown_9.Value;
            switch A
                case '无'
                    app.x(9)=0;
                case '有'
                    app.x(9)=1;
            end

            % 第10个自变量：运动强度x(10)
            A=app.DropDown_5.Value;
            switch A
                case '轻度运动'
                    app.x(10)=1;
                case '中度运动'
                    app.x(10)=2;
                case '强度运动'
                    app.x(10)=3;
            end

            % 第11个变量：高原反应史
            A=app.DropDown_6.Value;
            switch A
                case '否'
                    app.x(11)=1;
                case '是'
                    app.x(11)=2;
                case '第一次上高原'
                    app.x(11)=3;
            end
            
            load lastmd.mat
            yfit = lastmd.predictFcn(app.x);
            if yfit==0
                app.PredictOutcomesTextArea.Value='预测LLSS评分<3分，出现AMS风险较低';
            else
                app.PredictOutcomesTextArea.Value='预测LLSS评分≥3分，出现AMS风险较高';
            end
            
        end

        % Value changed function: PredictOutcomesTextArea
        function PredictOutcomesTextAreaValueChanged(app, event)
           
        end

        % Button pushed function: ResultStorageButton
        function ResultStorageButtonPushed(app, event)
            [filename1,path]=uiputfile({'*.txt'},'保存文件');
            name=fullfile(path,filename1);
            A=app.PredictOutcomesTextArea.Value;
            B=['编号' num2str(app.PatientIDEditField.Value) A];
            writecell(B,name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = {'Prognostic Prediction System for Patients '; 'with COVID-19 and Hypertension'};
            app.StartPredictionButton = uibutton(app.UIFigure, 'push');
            app.StartPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @StartPredictionButtonPushed, true);
            app.StartPredictionButton.Icon = fullfile(pathToMLAPP, '预测结果.png');
            app.StartPredictionButton.Text = 'Start Prediction';
            app.BasicInformationPanel = uipanel(app.UIFigure);
            app.BasicInformationPanel.Title = 'Basic Information';
            app.Label_3 = uilabel(app.BasicInformationPanel);
            app.Label_3.Text = 'Age';
            app.AgeEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.AgeEditField.Value = 76;
            app.MulbstascoreEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.MulbstascoreEditFieldLabel.Text = 'Mulbsta score';
            app.MulbstascoreEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.MulbstascoreEditField.Value = 9;
            app.IL6EditFieldLabel = uilabel(app.BasicInformationPanel);
            app.IL6EditFieldLabel.Text = 'IL-6';
            app.IL6EditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.IL6EditField.Value = 18;
            app.CRPEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.CRPEditFieldLabel.Text = 'CRP';
            app.CRPEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.CRPEditField.Value = 36.35;
            app.LDHEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.LDHEditFieldLabel.Text = 'LDH';
            app.LDHEditField = uieditfield(app.BasicInformationPanel, 'numeric');
            app.LDHEditField.Value = 245;
            app.ACEIARBDropDownLabel = uilabel(app.BasicInformationPanel);
            app.ACEIARBDropDownLabel.Text = 'ACEI/ARB';
            app.ACEIARBDropDown = uidropdown(app.BasicInformationPanel);
            app.ACEIARBDropDown.Items = {'Yes', 'No'};
            app.ACEIARBDropDown.Value = 'Yes';
            app.ResultStorageButton = uibutton(app.UIFigure, 'push');
            app.ResultStorageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultStorageButtonPushed, true);
            app.ResultStorageButton.Icon = fullfile(pathToMLAPP, '方案保存.png');
            app.ResultStorageButton.Text = 'Result Storage';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = 'Predict Outcomes';
            app.PredictOutcomesTextArea = uitextarea(app.UIFigure);
            app.PredictOutcomesTextArea.ValueChangedFcn = createCallbackFcn(app, @PredictOutcomesTextAreaValueChanged, true);
            app.PredictOutcomesTextArea.Value = {'The patient has a poor clinical prognosis and needs to be given special attention!'};
            app.CleaningButton = uibutton(app.UIFigure, 'push');
            app.CleaningButton.Icon = fullfile(pathToMLAPP, '清除.png');
            app.CleaningButton.Text = 'Cleaning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end