classdef password < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PasswordEditField       matlab.ui.control.NumericEditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        AccountEditField        matlab.ui.control.NumericEditField
        AccountEditFieldLabel   matlab.ui.control.Label
        LoginButton             matlab.ui.control.Button
        GAOHPAHPAHPLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            account=app.AccountEditField.Value;
            password=app.PasswordEditField.Value;
            %判断
            if account==123 && password==123
                %打开一个新的界面
                delete(app.UIFigure);
                run AHP.mlapp;
                return
            else
                errordlg('账户或者密码错误','错误提示','error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            choice=uiconfirm(app.UIFigure,'您确定关闭么？','关闭','Icon','warning', ...
                'Options',{'确定','否'},'DefaultOption',2);
            switch choice
                case  '确定'
                    delete(app.UIFigure)
                    return;
                case  '否'
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GAOHPAHPAHPLabel = uilabel(app.UIFigure);
            app.GAOHPAHPAHPLabel.Text = {'Prognostic Prediction System for Patients '; 'with COVID-19 and Hypertension'};
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.AccountEditFieldLabel = uilabel(app.UIFigure);
            app.AccountEditFieldLabel.Text = 'Account';
            app.AccountEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccountEditField.ValueDisplayFormat = '%.0f';
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'numeric');
            app.PasswordEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = password

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end